classdef FinalProjectx < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        ResurcesTab                 matlab.ui.container.Tab
        CostBox                     matlab.ui.control.CheckBox
        UndergradBox                matlab.ui.control.CheckBox
        RateBox                     matlab.ui.control.CheckBox
        ACTBox                      matlab.ui.control.CheckBox
        SATBox                      matlab.ui.control.CheckBox
        EarningsBox                 matlab.ui.control.CheckBox
        RetentionRateCheckBox       matlab.ui.control.CheckBox
        UITable2                    matlab.ui.control.Table
        ProcessButton               matlab.ui.control.Button
        UniversityIDEditFieldLabel  matlab.ui.control.Label
        univ_id                     matlab.ui.control.NumericEditField
        UniversityStatisticsLabel   matlab.ui.control.Label
        YearEditFieldLabel          matlab.ui.control.Label
        Years                       matlab.ui.control.NumericEditField
        DataTab                     matlab.ui.container.Tab
        UITable                     matlab.ui.control.Table
        ResultsTab                  matlab.ui.container.Tab
        TextAreaLabel               matlab.ui.control.Label
        TextArea                    matlab.ui.control.TextArea
        DisplayButton               matlab.ui.control.Button
        ACTTab                      matlab.ui.container.Tab
        ACT_Bar                     matlab.ui.control.UIAxes
        RetentionRateTab            matlab.ui.container.Tab
        Ret_Bar                     matlab.ui.control.UIAxes
        CollegeEarningsTab          matlab.ui.container.Tab
        Earn_Bar                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Data % Description
        Second_Data
        Selected_Prop
        ID
        Year
        Resp
               
        URL % Description
        TableData % Description
        cost % resulting cost
        act %act score from school
        sat %sat score from school
        size %school undergraduate size
        acc_rate %acceptance rate
        earning %average earnings
        ret_rate %retention rates
        Text % Data in text file
        National_act % national average
        National_earning %national earnings average
        National_ret  %national retention rate
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load the data.Takes data from school data base to give user a list of schools and their IDs.
            load UnivID.mat
            name1 = xTry.INSTNM;
            Name = cellstr(name1);
            UID = xTry.UNITID;
            % Store the data in a table and display it in one of the App's tabs.
            app.Data = table(UID,Name);
            
            app.UITable.Data = table2cell(app.Data);           
             app.National_act = 20; % national average
        app.National_earning = 46000; %national earnings average
        app.National_ret = 79.8; %national retention rate
        end

        % Value changed function: RetentionRateCheckBox
        function RetentionRateCheckBoxValueChanged(app, event)
            value = app.RetentionRateCheckBox.Value;
            
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
           %Takes the ID from user input and the Year from user input and extracts the data from the API
            key = 'zh3FrSr8h2G3o30rRTFfK9IlY8Rixy7g5jV3eDue';
            app.ID = app.univ_id.Value;
            app.Year = app.Years.Value;
    app.URL = sprintf('https://api.data.gov/ed/collegescorecard/v1/schools?id=%d&api_key=%s',app.ID,key);
    app.Resp = webread(app.URL);
        app.Second_Data = eval(sprintf('app.Resp.results.x%d',app.Year));
    
    %Checks to see if Earning is checked & error checks to make sure the data isn't empty
    if  (app.EarningsBox.Value == 1) && (any(app.Second_Data.earnings.x7_yrs_after_entry.mean_earnings) == 1)
            app.earning = app.Second_Data.earnings.x7_yrs_after_entry.mean_earnings;
    else 
        app.earning = [0];
    end
    %Checks to see if Cost is checked & error checks to make sure the data isn't empty
    if (app.CostBox.Value == 1) && (any(app.Second_Data.cost.avg_net_price.overall) == 1)
        app.cost = app.Second_Data.cost.avg_net_price.overall;
    else
        app.cost = {'NULL'};
    end
    %Checks to see if Retention is checked & error checks to make sure the data isn't empty
    if (app.RetentionRateCheckBox.Value == 1) && (any(app.Second_Data.student.retention_rate.overall.full_time) == 1)
        app.ret_rate = app.Second_Data.student.retention_rate.overall.full_time;
        app.ret_rate = app.ret_rate*100;
    else
        app.ret_rate = [0];
    end
    %Checks to see if Acceptance is checked & error checks to make sure the data isn't empty
    if (app.RateBox.Value == 1) && (any(app.Second_Data.admissions.admission_rate.overall) == 1)
        app.acc_rate = app.Second_Data.admissions.admission_rate.overall;
        app.acc_rate = app.acc_rate * 100;
    else
        app.acc_rate = {'NULL'};
    end
    %Checks to see if Size is checked & error checks to make sure the data isn't empty
    if (app.UndergradBox.Value == 1) && (any(app.Second_Data.student.size) == 1)
        app.size = app.Second_Data.student.size;
    else
        app.size = {'NULL'};
    end
    %Checks to see if SAT is checked & error checks to make sure the data isn't empty
    if (app.SATBox.Value == 1) && (any(app.Second_Data.admissions.sat_scores.average.overall) == 1)
        app.sat = app.Second_Data.admissions.sat_scores.average.overall;
    else
        app.sat = [0];
    end
    %Checks to see if ACT is checked & error checks to make sure the data isn't empty
    if (app.ACTBox.Value == 1) && (any(app.Second_Data.admissions.act_scores.midpoint.cumulative) == 1)
        app.act = app.Second_Data.admissions.act_scores.midpoint.cumulative;
    else 
        app.act = [0];
    end
    %Takes the data and turns it into a table and the stores the data in the UItable
    app.TableData = table(app.cost,app.size,app.acc_rate,app.act,app.sat,app.earning,app.ret_rate);
            app.UITable2.Data = table2cell(app.TableData); 


        end

        % Callback function
        function univ_idValueChanged(app, event)
            value = app.univ_id.Value;
            
        end

        % Callback function
        function UITableCellSelection(app, event)
            
        end

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)
          %Creates a file that will contain the information processed on the school.
            fid = fopen('project_result.txt', 'at');
            fprintf(fid,"University iD: %d\n",app.ID);
            %Writes to the file the information as long as the information is processed through the api.
            if isnumeric(app.cost) == 1
                fprintf(fid,"The average cost of the Univeristy is: %d\n",app.cost);
            end
            if isnumeric(app.size) == 1
                fprintf(fid,"The # of undergraduates at the schol is: %d\n", app.size);
            end
            if isnumeric(app.acc_rate) == 1
                fprintf(fid,"The acceptance rate is: %.2f\n", app.acc_rate);
            end
            if isnumeric(app.act) == 1
                fprintf(fid,"The average ACT score is: %d\n", app.act);
            end
            if isnumeric(app.sat) == 1
                fprintf(fid,"The average SAT score is: %d\n", app.sat);
            end
            if isnumeric(app.earning) == 1
                fprintf(fid,"The average earnings after college is: %d\n", app.earning);
            end
            if isnumeric(app.ret_rate) == 1
                fprintf(fid,"The retention rate of the school is: %.2f\n", app.ret_rate);
            end
            fclose(fid);
            app.Text = fileread('project_result.txt');
            app.TextArea.Value = app.Text;
            fclose('all');
              %creates bar graph for ACT
            actcat = categorical({'School','National Average'});
            scores = [app.act,app.National_act];
            if app.act > 0
            bar(app.ACT_Bar,actcat,scores);
            end
            %Creates bar graph for retention rate
            retcat = categorical({'School','National Average'});
            scores1 = [app.ret_rate,app.National_ret];
            if app.ret_rate > 0
            bar(app.Ret_Bar,retcat,scores1);
            end
            %Creates bar graph for earnings
            earncat = categorical({'School','National Average'});
            scores2 = [app.earning,app.National_earning];
            if app.earning > 0 
            bar(app.Earn_Bar,earncat,scores2);   
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ResurcesTab = uitab(app.TabGroup);
            app.ResurcesTab.Title = 'Resurces';
            app.CostBox = uicheckbox(app.ResurcesTab);
            app.CostBox.Text = 'Average Cost';
            app.CostBox.Value = true;
            app.UndergradBox = uicheckbox(app.ResurcesTab);
            app.UndergradBox.Text = '# of Undergraduates';
            app.UndergradBox.Value = true;
            app.RateBox = uicheckbox(app.ResurcesTab);
            app.RateBox.Text = 'Acceptance Rate';
            app.RateBox.Value = true;
            app.ACTBox = uicheckbox(app.ResurcesTab);
            app.ACTBox.Text = 'Average ACT ';
            app.ACTBox.Value = true;
            app.SATBox = uicheckbox(app.ResurcesTab);
            app.SATBox.Text = 'Average SAT';
            app.SATBox.Value = true;
            app.EarningsBox = uicheckbox(app.ResurcesTab);
            app.EarningsBox.Text = 'Average Earnings';
            app.EarningsBox.Value = true;
            app.RetentionRateCheckBox = uicheckbox(app.ResurcesTab);
            app.RetentionRateCheckBox.ValueChangedFcn = createCallbackFcn(app, @RetentionRateCheckBoxValueChanged, true);
            app.RetentionRateCheckBox.Text = 'Retention Rate';
            app.RetentionRateCheckBox.Value = true;
            app.UITable2 = uitable(app.ResurcesTab);
            app.UITable2.ColumnName = {'Average Cost'; 'Undergraduates'; 'Acceptance Rate'; 'Average ACT'; 'Average SAT'; 'Average Earnings'; 'Retention Rate'};
            app.UITable2.RowName = {};
            app.ProcessButton = uibutton(app.ResurcesTab, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.UniversityIDEditFieldLabel = uilabel(app.ResurcesTab);
            app.UniversityIDEditFieldLabel.Text = 'University ID';
            app.univ_id = uieditfield(app.ResurcesTab, 'numeric');
            app.univ_id.Limits = [0 Inf];
            app.univ_id.ValueDisplayFormat = '%.0f';
            app.univ_id.Value = 164924;
            app.UniversityStatisticsLabel = uilabel(app.ResurcesTab);
            app.UniversityStatisticsLabel.Text = 'University Statistics';
            app.YearEditFieldLabel = uilabel(app.ResurcesTab);
            app.YearEditFieldLabel.Text = 'Year';
            app.Years = uieditfield(app.ResurcesTab, 'numeric');
            app.Years.Limits = [2009 2015];
            app.Years.ValueDisplayFormat = '%.0f';
            app.Years.Value = 2009;
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.UITable = uitable(app.DataTab);
            app.UITable.ColumnName = {'ID'; 'University'};
            app.UITable.RowName = {};
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.TextAreaLabel = uilabel(app.ResultsTab);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.ResultsTab);
            app.TextArea.Editable = 'off';
            app.DisplayButton = uibutton(app.ResultsTab, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplayButton.Text = 'Display';
            app.ACTTab = uitab(app.TabGroup);
            app.ACTTab.Title = 'ACT';
            app.ACT_Bar = uiaxes(app.ACTTab);
            title(app.ACT_Bar, 'ACT vs National Avergae')
            xlabel(app.ACT_Bar, 'School & Average')
            ylabel(app.ACT_Bar, 'ACT Score')
            app.ACT_Bar.XLim = [0 1];
            app.ACT_Bar.YLim = [0 1];
            app.ACT_Bar.ZLim = [0 1];
            app.ACT_Bar.CLim = [0 1];
            app.ACT_Bar.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ACT_Bar.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.ACT_Bar.ZTick = [0 0.5 1];
            app.RetentionRateTab = uitab(app.TabGroup);
            app.RetentionRateTab.Title = 'Retention Rate';
            app.Ret_Bar = uiaxes(app.RetentionRateTab);
            title(app.Ret_Bar, 'Retention Rate vs National Avergae')
            xlabel(app.Ret_Bar, 'School & Average')
            ylabel(app.Ret_Bar, 'Retention Rate ')
            app.Ret_Bar.XLim = [0 1];
            app.Ret_Bar.YLim = [0 1];
            app.Ret_Bar.ZLim = [0 1];
            app.Ret_Bar.CLim = [0 1];
            app.Ret_Bar.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Ret_Bar.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.Ret_Bar.ZTick = [0 0.5 1];
            app.CollegeEarningsTab = uitab(app.TabGroup);
            app.CollegeEarningsTab.Title = 'College Earnings';
            app.Earn_Bar = uiaxes(app.CollegeEarningsTab);
            title(app.Earn_Bar, 'Graduate Earnings vs National Average')
            xlabel(app.Earn_Bar, 'School & Average')
            ylabel(app.Earn_Bar, 'Earnings (Tens of Thousands)')
            app.Earn_Bar.XLim = [0 1];
            app.Earn_Bar.YLim = [0 1];
            app.Earn_Bar.ZLim = [0 1];
            app.Earn_Bar.CLim = [0 1];
            app.Earn_Bar.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Earn_Bar.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.Earn_Bar.ZTick = [0 0.5 1];
        end
    end

    methods (Access = public)

        % Construct app
        function app = FinalProjectx

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end